classdef EditROIApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ROInameLabel       matlab.ui.control.Label
        ColorLabel         matlab.ui.control.Label
        ROIeditorLabel     matlab.ui.control.Label
        NameText           matlab.ui.control.EditField
        Colorbox           matlab.ui.control.EditField
        EditcolorButton    matlab.ui.control.Button
        SavechangesButton  matlab.ui.control.Button
        CancelButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp; % CallingApp is the app (MainWindow) opening the EditROIApp. 
    end
    methods (Access = private)
        
        function RedrawROIs(app)
            
            % images shown on the axes in the MainWindow is redrawn for the purpose of removing all drawn ROIs as the color of the ROIs has changed:
            % https://se.mathworks.com/help/matlab/creating_plots/the-image-object-and-its-properties.html
            imshow(app.CallingApp.ax.Children(length(app.CallingApp.ax.Children)).CData,'parent', app.CallingApp.ax);
            
            %MainWindow's 'displayROIs' method is called to redraw the ROIs on the image with the new color. 
            app.CallingApp.displayROIs(app.CallingApp.SliceField.Value,app.CallingApp.FrameField.Value);
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %Calling app is set to mainapp:
            app.CallingApp = mainapp;
            %The app is opened with the name and color of the ROI collection filled in:
            app.NameText.Value = app.CallingApp.RoiController.ROICollections{app.CallingApp.ROIRowIndex}.Name;
            app.Colorbox.BackgroundColor = app.CallingApp.RoiController.ROICollections{app.CallingApp.ROIRowIndex}.Color;
        end

        % Button pushed function: EditcolorButton
        function EditColorPressed(app, event)
            
            % Color picker is opened, allowing the user to pick a costum color:
            % https://se.mathworks.com/help/matlab/ref/uisetcolor.html
            color = uisetcolor('RGB');
            if(length(color)>1)
                app.Colorbox.BackgroundColor = color;
            end
            %Work-around to prevent the user interface from minimizing:
            app.CallingApp.BOLDAnalysisToolUIFigure.Visible = 'off';
            app.CallingApp.BOLDAnalysisToolUIFigure.Visible = 'on';
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: CancelButton
        function CancelEditPressed(app, event)
            %Close the app:
            close(app.UIFigure);
        end

        % Button pushed function: SavechangesButton
        function SaveEditPressed(app, event)
            % When save is pressed, the name and color of the collection is changed in the RoiController in the MainWindow:
            app.CallingApp.RoiController.ROICollections{app.CallingApp.ROIRowIndex}.Name = app.NameText.Value;
            app.CallingApp.RoiController.ROICollections{app.CallingApp.ROIRowIndex}.Color = app.Colorbox.BackgroundColor;
            % a new table with the new names of the ROI collections is generated:
            [cola, colb] = app.CallingApp.RoiController.getROITable;
            app.CallingApp.UITable.Data = [cola,colb];
            %The ROIs and the BOLD graphs in the MainWindow are updated and the EditROIApp is closed 
            app.CallingApp.plotAbsAndRel;
            app.RedrawROIs;
            close(app.UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ROInameLabel = uilabel(app.UIFigure);
            app.ROInameLabel.Text = 'ROI name:';
            app.ColorLabel = uilabel(app.UIFigure);
            app.ColorLabel.Text = 'Color:';
            app.ROIeditorLabel = uilabel(app.UIFigure);
            app.ROIeditorLabel.Text = 'ROI editor';
            app.NameText = uieditfield(app.UIFigure, 'text');
            app.Colorbox = uieditfield(app.UIFigure, 'text');
            app.Colorbox.Editable = 'off';
            app.EditcolorButton = uibutton(app.UIFigure, 'push');
            app.EditcolorButton.ButtonPushedFcn = createCallbackFcn(app, @EditColorPressed, true);
            app.EditcolorButton.Text = 'Edit color';
            app.SavechangesButton = uibutton(app.UIFigure, 'push');
            app.SavechangesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEditPressed, true);
            app.SavechangesButton.Text = 'Save changes';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelEditPressed, true);
            app.CancelButton.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EditROIApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end