classdef MainWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BOLDAnalysisToolUIFigure    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        NewMenu_2                   matlab.ui.container.Menu
        OpennewMenu                 matlab.ui.container.Menu
        OpenexistingMenu            matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        ExporttoExcelMenu           matlab.ui.container.Menu
        ExitMenu                    matlab.ui.container.Menu
        ROIMenu                     matlab.ui.container.Menu
        NewcollectionMenu           matlab.ui.container.Menu
        AboutMenu                   matlab.ui.container.Menu
        CreditsMenu                 matlab.ui.container.Menu
        UITable                     matlab.ui.control.Table
        MetadataPanel               matlab.ui.container.Panel
        MetaTable                   matlab.ui.control.Table
        MetaBtn                     matlab.ui.control.Button
        ROIinformationPanel         matlab.ui.container.Panel
        AveragepixelvalueLabel      matlab.ui.control.Label
        AveragepixelvalueEditField  matlab.ui.control.NumericEditField
        StandarddeviationLabel      matlab.ui.control.Label
        StandarddeviationEditField  matlab.ui.control.NumericEditField
        TabGroup                    matlab.ui.container.TabGroup
        RelativeBOLDTab             matlab.ui.container.Tab
        UIAxes2                     matlab.ui.control.UIAxes
        BaselineEditFieldLabel      matlab.ui.control.Label
        BaselineEditField           matlab.ui.control.NumericEditField
        BtnOpenGraphRel             matlab.ui.control.Button
        BtnRestoreGraphRel          matlab.ui.control.Button
        BtnUpdateGraphRel           matlab.ui.control.Button
        AbsoluteBOLDTab             matlab.ui.container.Tab
        UIAxes2_2                   matlab.ui.control.UIAxes
        BtnOpenGraphAbs             matlab.ui.control.Button
        BtnRestoreGraphAbs          matlab.ui.control.Button
        BtnUpdateGraphAbs           matlab.ui.control.Button
        DeleteROICollection         matlab.ui.control.Button
        SettingsBtn                 matlab.ui.control.Button
        GammacorrectionPanel        matlab.ui.container.Panel
        CoefficientSliderLabel      matlab.ui.control.Label
        CoefficientSlider           matlab.ui.control.Slider
        Label                       matlab.ui.control.Label
        Switch                      matlab.ui.control.Switch
        CoefficientField            matlab.ui.control.NumericEditField
        SliceField                  matlab.ui.control.NumericEditField
        SliceSliderLabel            matlab.ui.control.Label
        SliceSlider                 matlab.ui.control.Slider
        FrameField                  matlab.ui.control.NumericEditField
        FrameSliderLabel            matlab.ui.control.Label
        FrameSlider                 matlab.ui.control.Slider
        UpdateROIInfo               matlab.ui.control.Button
        WelcomeLabel                matlab.ui.control.Label
        WelcomeLabel2               matlab.ui.control.Label
        WelcomeLabel3               matlab.ui.control.Label
        ToolsPanel                  matlab.ui.container.Panel
        BtnZoomOut                  matlab.ui.control.Button
        BtnContinueROI              matlab.ui.control.Button
        BtnGammaCorShow             matlab.ui.control.StateButton
        BtnHistEq                   matlab.ui.control.StateButton
        BtnDeleteROI                matlab.ui.control.Button
        Button                      matlab.ui.control.Button
        BtnDeleteProceedingROIs     matlab.ui.control.Button
        BtnDrawROIThrough           matlab.ui.control.Button
    end

    %Properties used in the main window
    properties (Access = public)
        numberOfFrames; % Total number of frames
        numberOfSlices; % Total number of slices
        dicomDisplay; % Controls the logic of showing the correct image
        contrastAdjuster; % Controls the logic of applying contrast methods
        dicomFile; % Holds pixeldata and metadata for one image at a time
        modifiedDicomHist; % Holds the pixeldata of the hist. eq. image
        modifiedDicomGamma; % Holds the pixeldata of the g. cor. image
        xaxeslims;
        yaxeslims;
        RoiController; % Controls the logic of handling ROIs when they are drawn
        ax; % Used as an Axes component instead of UIAxes, becasuse UIAxes does not support "Drawpolygon" in the current release.
        ROIRowIndex; % The index in the table
        BoldAnalyzer; % Controls the logic of performing a BOLD analysis
        contrastIdentifier; % Keeps track of which contrastadjustement method has been used
        fileHandler; % Controls the logic of saving files, and loading a previously saved one.
    end
    
    properties (Access = private)
        MetaApp; %Property to call the MetaApp to display Dicominfo.
        EditROIApp;
    end
    
    methods (Access = private)
        
        function saveROIPositions(app, slicenumber, framenumber)
            %saveROIPositions saves polygon positions when the user moves it. As there is no callback, this is called when chaning frame and slice.
            for i=1: length(app.RoiController.ROICollections)
                if(app.RoiController.ROICollections{i}.AnalysisStatus == 1)
                    if(isfield(app.RoiController.ROICollections{i}.ROIs{slicenumber},'Frames')) 
                        if(length(app.RoiController.ROICollections{i}.ROIs{slicenumber}.Frames)>=framenumber)
                            for j=1: length(app.RoiController.ROICollections{i}.ROIs{slicenumber}.Frames{framenumber}.ROI)
                                if(~isempty(app.RoiController.ROICollections{i}.ROIs{slicenumber}.Frames{framenumber}.Position{j}))
                                    app.RoiController.ROICollections{i}.ROIs{slicenumber}.Frames{framenumber}.Position{j}= app.RoiController.ROICollections{i}.ROIs{slicenumber}.Frames{framenumber}.ROI{j}.Position;
                                end
                            end  
                        end
                    end
                end
                app.BoldAnalyzer.AddValueForFrame(i, app.dicomDisplay, app.RoiController, app.SliceField.Value, app.FrameField.Value);
                app.plotAbsAndRel;
             end
        end
    end
    
    methods (Access = public)
        
        function displayROIs(app,slicenumber, framenumber)
            %displayROIs make sure to show the ROIs when changing between images.
            if(isempty(app.modifiedDicomGamma) && isempty(app.modifiedDicomHist))
                imshow(app.dicomFile.pixelData,'parent',app.ax);
            else
                if(app.contrastIdentifier ==1)
                    imshow(app.modifiedDicomGamma,'parent',app.ax);
                else
                    imshow(app.modifiedDicomHist,'parent',app.ax);
                end
            end
            for i=1: length(app.RoiController.ROICollections)
                if(app.RoiController.ROICollections{i}.AnalysisStatus == 1)
                    if(isfield(app.RoiController.ROICollections{i}.ROIs{slicenumber},'Frames'))
                        if(length(app.RoiController.ROICollections{i}.ROIs{slicenumber}.Frames)>=framenumber)
                            if(isfield(app.RoiController.ROICollections{i}.ROIs{slicenumber}.Frames{framenumber}, 'ROI'))
                                for j=1: length(app.RoiController.ROICollections{i}.ROIs{slicenumber}.Frames{framenumber}.Position)
                                    if(~isempty(app.RoiController.ROICollections{i}.ROIs{slicenumber}.Frames{framenumber}.Position{j}))
                                        polygon = app.RoiController.ROICollections{i}.ROIs{slicenumber}.Frames{framenumber}.Position{j};
                                        ROI = drawpolygon(app.ax,'Position',polygon,'Color',app.RoiController.ROICollections{i}.Color,'LineWidth',1);
                                        app.RoiController.ROICollections{i}.ROIs{slicenumber}.Frames{framenumber}.ROI{j} = ROI;
                                    end
                                end
                            end
                        end
                    end
                end
            end
        end

        function getAbsValues(app)
            %getAbsValues adds a absolute value to the frame
            app.BoldAnalyzer.AddValueForFrame(app.ROIRowIndex, app.dicomDisplay, app.RoiController, app.SliceField.Value, app.FrameField.Value)
        end
    
        function plotAbsAndRel(app)
            %plotAbsAndRel makes sure to plot the graph whenever changes are made.
            AbsValues = app.BoldAnalyzer.absValues;
            cla(app.UIAxes2_2);
            cla(app.UIAxes2); 
            legendCounter = 1;
            hold(app.UIAxes2_2,'on');
            hold(app.UIAxes2,'on');
            for h=1:length(app.RoiController.ROICollections)
                if(app.RoiController.ROICollections{h}.AnalysisStatus == 1)
                      if(~isempty(AbsValues{h}))
                        RelValues = app.BoldAnalyzer.GetRelativeValues(AbsValues{h}.CollectionValues,app.BaselineEditField.Value);
                        plot(app.UIAxes2_2, AbsValues{h}.XValues, AbsValues{h}.CollectionValues,'Color',app.RoiController.ROICollections{h}.Color);
                        plot(app.UIAxes2, AbsValues{h}.XValues, RelValues,'Color',app.RoiController.ROICollections{h}.Color);
                        legends{legendCounter} = app.RoiController.ROICollections{h}.Name;
                        legendCounter = legendCounter+1;
                      end                   
                end
            end
            if(exist('legends','var'))
                legend(app.UIAxes2_2,legends);
                legend(app.UIAxes2,legends);
                hold(app.UIAxes2_2,'off');
                hold(app.UIAxes2_2,'off');
            end
        end
        
        function updateAvgAndStdFields(app)
            %updateAbgAndStdFields makes sure to update the fields on the user interface when changing image and selecting different ROIs.
            if(~isempty(app.ROIRowIndex))
                [average, standardDev] = app.BoldAnalyzer.GetFrameMeanAndStd(app.RoiController,app.ROIRowIndex,app.FrameField.Value, app.SliceField.Value,app.dicomDisplay);
                if(~isnan(average) && ~isnan(standardDev))
                    app.AveragepixelvalueEditField.Value = average;
                    app.StandarddeviationEditField.Value = standardDev;
                else 
                    app.AveragepixelvalueEditField.Value = 0;
                    app.StandarddeviationEditField.Value = 0;
                end
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dicomDisplay = DicomDisplay;
            app.dicomDisplay = app.dicomDisplay.Constructor();
            app.contrastAdjuster = ContrastAdjuster;
            app.dicomFile = DICOM_File_DTO;
            app.modifiedDicomGamma = [];
            app.modifiedDicomHist = [];
            app.RoiController = ROIController;
            app.ax = axes(app.BOLDAnalysisToolUIFigure,'Position',[0,0.3,0.6,0.7],'XTickLabel',[],'YTickLabel',[]);
            app.ax.XAxis.Color = 'w';
            app.ax.YAxis.Color = 'w';
            app.BoldAnalyzer = BOLDAnalyzer;
            app.BoldAnalyzer = app.BoldAnalyzer.Constructor();
            %app.UITable.ColumnEditable = [false,true];
            
            app.UITable.ColumnFormat = {'char','logical'};
%             app.UITable.Data = table({'test';'test2'},{true;false});

            app.fileHandler = FileHandler;
            app.fileHandler = app.fileHandler.Constructor();
        end

        % Key press function: BOLDAnalysisToolUIFigure
        function BOLDAnalysisToolUIFigureKeyPress(app, event)
            key = event.Key;
            tic
            if strcmp(key, 'rightarrow') == 1 && app.FrameSlider.Value < max(app.FrameSlider.Limits)
                app.saveROIPositions(app.SliceField.Value,app.FrameField.Value);
                app.FrameSlider.Value = app.FrameSlider.Value + 1;
                app.FrameField.Value = app.FrameField.Value + 1;
                app.dicomFile = app.dicomDisplay.ChangeDICOM_file(app.SliceSlider.Value, app.FrameSlider.Value);
                imshow(app.dicomFile.pixelData,'parent',app.ax);
                app.modifiedDicomGamma = [];
                app.modifiedDicomHist = [];
                app.Switch.Value = 'Off';
                app.BtnHistEq.Value = 0; 
                app.MetaTable.Data = app.dicomFile.dicomInfo;    
                app.displayROIs(app.SliceField.Value,app.FrameField.Value);
                app.updateAvgAndStdFields;
            end
            
            if strcmp(key, 'leftarrow') == 1 && app.FrameSlider.Value > min(app.FrameSlider.Limits)
                app.saveROIPositions(app.SliceField.Value,app.FrameField.Value);
                app.FrameSlider.Value = app.FrameSlider.Value - 1;
                app.FrameField.Value = app.FrameField.Value - 1;
                app.dicomFile = app.dicomDisplay.ChangeDICOM_file(app.SliceSlider.Value, app.FrameSlider.Value);
                imshow(app.dicomFile.pixelData,'parent',app.ax);
                app.modifiedDicomGamma = [];
                app.modifiedDicomHist = [];
                app.Switch.Value = 'Off';
                app.BtnHistEq.Value = 0;
                app.MetaTable.Data = app.dicomFile.dicomInfo;    
                app.displayROIs(app.SliceField.Value,app.FrameField.Value);
                app.updateAvgAndStdFields;
            end
            
            if strcmp(key, 'uparrow') == 1 && app.SliceSlider.Value < max(app.SliceSlider.Limits)
                app.saveROIPositions(app.SliceField.Value,app.FrameField.Value);
                app.SliceSlider.Value = app.SliceSlider.Value + 1;
                app.SliceField.Value = app.SliceField.Value + 1;
                app.dicomFile = app.dicomDisplay.ChangeDICOM_file(app.SliceSlider.Value, app.FrameSlider.Value);
                imshow(app.dicomFile.pixelData,'parent',app.ax);
                app.modifiedDicomGamma = [];
                app.modifiedDicomHist = [];
                app.Switch.Value = 'Off';
                app.BtnHistEq.Value = 0;
                app.MetaTable.Data = app.dicomFile.dicomInfo;
                app.displayROIs(app.SliceField.Value,app.FrameField.Value);
                app.updateAvgAndStdFields;
            end
            
            if strcmp(key, 'downarrow') == 1 && app.SliceSlider.Value > min(app.SliceSlider.Limits)
                app.saveROIPositions(app.SliceField.Value,app.FrameField.Value);
                app.SliceSlider.Value = app.SliceSlider.Value - 1;
                app.SliceField.Value = app.SliceField.Value - 1;
                app.dicomFile = app.dicomDisplay.ChangeDICOM_file(app.SliceSlider.Value, app.FrameSlider.Value);
                imshow(app.dicomFile.pixelData,'parent',app.ax);
                app.modifiedDicomGamma = [];
                app.modifiedDicomHist = [];
                app.Switch.Value = 'Off';
                app.BtnHistEq.Value = 0;
                app.MetaTable.Data = app.dicomFile.dicomInfo;    
                app.displayROIs(app.SliceField.Value,app.FrameField.Value);
                app.updateAvgAndStdFields;
            end
            toc
              
        end

        % Menu selected function: OpennewMenu
        function OpenDialogPress2(app, event)
            folder_path = uigetdir();
            if(folder_path~=0)
                %A try-catch is used to warn the user, if something goes wrong during the load
                try
                    [app.dicomFile, app.numberOfFrames, app.numberOfSlices] = app.dicomDisplay.ReadDicomFiles(folder_path);
                    app.FrameSlider.Limits = [1 app.numberOfFrames];
                    app.SliceSlider.Limits = [1 app.numberOfSlices];
                    imshow(app.dicomFile.pixelData,'parent',app.ax);
                    app.modifiedDicomGamma = [];
                    app.modifiedDicomHist = [];
                    app.MetaTable.Data = app.dicomFile.dicomInfo;   
                    app.xaxeslims = app.ax.XLim;
                    app.yaxeslims = app.ax.YLim;
                    app.WelcomeLabel.Visible = 'off';
                    app.WelcomeLabel2.Visible = 'off';
                    app.WelcomeLabel3.Visible = 'off';
                    app.MetaBtn.Enable = 'on';
                    app.UpdateROIInfo.Enable = 'on';
                    app.BtnHistEq.Enable = 'on';
                    app.BtnGammaCorShow.Enable = 'on';
                    app.BtnZoomOut.Enable = 'on';
                    app.FrameSlider.Enable = 'on';
                    app.SliceSlider.Enable = 'on';
                    app.FrameField.Enable = 'on';
                    app.SliceField.Enable = 'on';
                    app.NewcollectionMenu.Enable = 'on';
                    app.ExporttoExcelMenu.Enable = 'on';
                    app.NewcollectionMenu.Enable = 'on';
                    app.SaveMenu.Enable = 'on';
                catch 
                    message = sprintf('ERROR: Could not read files. Make sure the specified folder contains only DICOM-files, and that these are not arranged in any subfolders');
                    uialert(app.BOLDAnalysisToolUIFigure,message,'Error opening folder', 'Icon', 'error');
                end
            end
            app.BOLDAnalysisToolUIFigure.Visible = 'off';
            app.BOLDAnalysisToolUIFigure.Visible = 'on';
        end

        % Callback function
        function BtnZoomInButtonPushed(app, event)
            if(app.BtnZoomIn.Value == 1)
                zoom(app.ax,'on');
            else 
                zoom(app.ax,'off');
            end            
        end

        % Button pushed function: BtnZoomOut
        function BtnZoomOutButtonPushed(app, event)
            app.ax.XLim = app.xaxeslims;
            app.ax.YLim = app.yaxeslims;
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            app.saveROIPositions(app.SliceField.Value,app.FrameField.Value);
            app.FrameField.Value = round(app.FrameSlider.Value);
            app.FrameSlider.Value = round(app.FrameSlider.Value); 
            app.dicomFile = app.dicomDisplay.ChangeDICOM_file(app.SliceField.Value, app.FrameField.Value);
            imshow(app.dicomFile.pixelData,'parent',app.ax);
            app.modifiedDicomGamma = [];
            app.modifiedDicomHist = [];
            app.Switch.Value = 'Off';
            app.BtnHistEq.Value = 0;   
            app.MetaTable.Data = app.dicomFile.dicomInfo;    
            app.displayROIs(app.SliceField.Value,app.FrameField.Value);
            app.updateAvgAndStdFields;
        end

        % Value changed function: FrameField
        function FrameFieldValueChanged(app, event)
            if(app.FrameField.Value <= max(app.FrameSlider.Limits) && app.FrameField.Value >= min(app.FrameSlider.Limits))
                app.saveROIPositions(app.SliceSlider.Value,app.FrameSlider.Value);
                app.FrameSlider.Value = app.FrameField.Value;
                app.dicomFile = app.dicomDisplay.ChangeDICOM_file(app.SliceSlider.Value, app.FrameSlider.Value);
                imshow(app.dicomFile.pixelData,'parent',app.ax);
                app.modifiedDicomGamma = [];
                app.modifiedDicomHist = [];
                app.Switch.Value = 'Off';
                app.BtnHistEq.Value = 0;  
                app.MetaTable.Data = app.dicomFile.dicomInfo;    
                app.displayROIs(app.SliceSlider.Value,app.FrameSlider.Value);
                app.updateAvgAndStdFields;
            else
                app.FrameField.Value = app.FrameSlider.Value;
            end
            
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            app.saveROIPositions(app.SliceField.Value,app.FrameField.Value);
            app.SliceField.Value = round(app.SliceSlider.Value);
            app.SliceSlider.Value = round(app.SliceSlider.Value); 
            app.dicomFile = app.dicomDisplay.ChangeDICOM_file(app.SliceField.Value, app.FrameField.Value);
            imshow(app.dicomFile.pixelData,'parent',app.ax); 
            app.modifiedDicomGamma = [];
            app.modifiedDicomHist = [];
            app.Switch.Value = 'Off';
            app.BtnHistEq.Value = 0;  
            app.MetaTable.Data = app.dicomFile.dicomInfo;    
            app.displayROIs(app.SliceField.Value,app.FrameField.Value);
            app.updateAvgAndStdFields;
        end

        % Value changed function: SliceField
        function SliceFieldValueChanged(app, event)
            if(app.SliceField.Value <= max(app.SliceSlider.Limits) && app.SliceField.Value >= min(app.SliceSlider.Limits))
                app.saveROIPositions(app.SliceSlider.Value,app.FrameSlider.Value);
                app.SliceSlider.Value = app.SliceField.Value;
                app.dicomFile = app.dicomDisplay.ChangeDICOM_file(app.SliceSlider.Value, app.FrameSlider.Value);
                imshow(app.dicomFile.pixelData,'parent',app.ax);
                app.modifiedDicomGamma = [];
                app.modifiedDicomHist = [];
                app.Switch.Value = 'Off';
                app.BtnHistEq.Value = 0;
                app.MetaTable.Data = app.dicomFile.dicomInfo;    
                app.displayROIs(app.SliceSlider.Value,app.FrameSlider.Value);
                app.updateAvgAndStdFields;
            else
                app.SliceField.Value = app.SliceSlider.Value;
            end
        end

        % Value changed function: BtnGammaCorShow
        function BtnGammaCorShowButtonPushed(app, event)
            if(app.BtnGammaCorShow.Value == 1)
                app.GammacorrectionPanel.Visible = 'on';
            else
                app.GammacorrectionPanel.Visible = 'off';
            end            
        end

        % Value changed function: Switch
        function gammaSwitchPressed(app, event)
           if(strcmp(app.Switch.Value, 'On'))
               app.contrastIdentifier = 1;
               app.CoefficientSlider.Enable = 'on';
               app.CoefficientField.Enable = 'on';
               if(isempty(app.modifiedDicomHist))
                   app.modifiedDicomGamma =  app.contrastAdjuster.GammaCorrect(app.CoefficientField.Value,app.dicomFile.pixelData);
               else
                   app.modifiedDicomGamma =  app.contrastAdjuster.GammaCorrect(app.CoefficientField.Value,app.modifiedDicomHist);
               end
               app.saveROIPositions(app.SliceField.Value,app.FrameField.Value);
               imshow(app.modifiedDicomGamma,'parent',app.ax);
               app.displayROIs(app.SliceField.Value, app.FrameField.Value);
           else
               app.CoefficientSlider.Enable = 'off';
               app.CoefficientField.Enable = 'off';
               app.modifiedDicomGamma =  [];
               if(app.BtnHistEq.Value == 1)
                  app.contrastIdentifier=2;
                  app.modifiedDicomHist = app.contrastAdjuster.HistogramEqualize(app.dicomFile.pixelData);
                  app.saveROIPositions(app.SliceField.Value,app.FrameField.Value);
                  imshow(app.modifiedDicomHist,'parent',app.ax);   
                  app.displayROIs(app.SliceField.Value, app.FrameField.Value);
               else
                  app.saveROIPositions(app.SliceField.Value,app.FrameField.Value);
                  imshow(app.dicomFile.pixelData,'parent',app.ax);    
                  app.displayROIs(app.SliceField.Value, app.FrameField.Value);
               end
           end            
        end

        % Value changed function: CoefficientField
        function CoefficientFieldChanged(app, event)
            if(app.CoefficientField.Value <= max(app.CoefficientSlider.Limits) && app.CoefficientField.Value >= min(app.CoefficientSlider.Limits))
                app.contrastIdentifier=1;
                
                if(isempty(app.modifiedDicomHist))
                    app.modifiedDicomGamma =  app.contrastAdjuster.GammaCorrect(app.CoefficientField.Value,app.dicomFile.pixelData);
                else
                    app.modifiedDicomGamma =  app.contrastAdjuster.GammaCorrect(app.CoefficientField.Value,app.modifiedDicomHist);
                end
                
                app.CoefficientSlider.Value = app.CoefficientField.Value;
                app.saveROIPositions(app.SliceField.Value,app.FrameField.Value);
                imshow(app.modifiedDicomGamma,'parent',app.ax);     
                app.displayROIs(app.SliceField.Value, app.FrameField.Value);
            else
                app.CoefficientField.Value = app.CoefficientSlider.Value;
            end
        end

        % Value changed function: BtnHistEq
        function HistBtnPressed(app, event)
           %Histogram equalizes the current image. This has to work with the gamma correction, so some logic that checks whether gamma cor. is enabled is used
            if(app.BtnHistEq.Value == 1)
                app.contrastIdentifier=2;
                if(strcmp(app.Switch.Value, 'On'))
                     app.modifiedDicomHist = app.contrastAdjuster.HistogramEqualize(app.modifiedDicomGamma);
                     app.modifiedDicomGamma = [];
                else
                     app.modifiedDicomHist = app.contrastAdjuster.HistogramEqualize(app.dicomFile.pixelData);
                end
                app.saveROIPositions(app.SliceField.Value,app.FrameField.Value);
                imshow(app.modifiedDicomHist,'parent',app.ax);  
                app.displayROIs(app.SliceField.Value, app.FrameField.Value);
            else
                app.modifiedDicomHist = [];
                if(strcmp(app.Switch.Value, 'On'))
                   app.contrastIdentifier=1;
                   app.modifiedDicomGamma = app.contrastAdjuster.GammaCorrect(app.CoefficientField.Value,app.dicomFile.pixelData);
                   app.saveROIPositions(app.SliceField.Value,app.FrameField.Value);
                   imshow(app.modifiedDicomGamma,'parent',app.ax);  
                   app.displayROIs(app.SliceField.Value, app.FrameField.Value);
                else
                   app.modifiedDicomGamma = []; 
                   app.saveROIPositions(app.SliceField.Value,app.FrameField.Value);
                   imshow(app.dicomFile.pixelData,'parent',app.ax);  
                   app.displayROIs(app.SliceField.Value, app.FrameField.Value);
                end
            end            
        end

        % Value changed function: CoefficientSlider
        function gammaValueChanged(app, event)
            
            app.CoefficientField.Value = app.CoefficientSlider.Value;
           
            if(isempty(app.modifiedDicomHist))
                app.modifiedDicomGamma =  app.contrastAdjuster.GammaCorrect(app.CoefficientField.Value,app.dicomFile.pixelData);
            else
                app.modifiedDicomGamma =  app.contrastAdjuster.GammaCorrect(app.CoefficientField.Value,app.modifiedDicomHist);
            end
            app.saveROIPositions(app.SliceField.Value,app.FrameField.Value);
            imshow(app.modifiedDicomGamma,'parent',app.ax);
            app.displayROIs(app.SliceField.Value, app.FrameField.Value);
        end

        % Button pushed function: MetaBtn
        function MetaBtnPushed(app, event)
            app.MetaApp = MetaApp(app, app.dicomFile.dicomInfo);
        end

        % Menu selected function: NewcollectionMenu
        function NewROIPressed2(app, event)
            app.ROIRowIndex = length(app.RoiController.ROICollections)+1;
            ROI = drawpolygon(app.ax,'Color','b','LineWidth',1);
            ROICollectionDTO = ROICollection;
            app.RoiController.AddNewCollection('new',max(app.FrameSlider.Limits),max(app.SliceSlider.Limits),[0 0 1],app.FrameField.Value,app.SliceField.Value,ROI,ROICollectionDTO);
            [cola, colb] = app.RoiController.getROITable;
            app.UITable.Data = [cola,colb];
            app.getAbsValues;
            app.plotAbsAndRel;
            app.updateAvgAndStdFields;
            app.AveragepixelvalueEditField.Enable = 'on';
            app.AveragepixelvalueLabel.Enable = 'on';
            app.StandarddeviationEditField.Enable = 'on';
            app.StandarddeviationLabel.Enable = 'on';
            app.BaselineEditField.Enable = 'on';
            app.BaselineEditFieldLabel.Enable = 'on';
            app.BtnOpenGraphRel.Enable = 'on';
            app.BtnUpdateGraphRel.Enable = 'on';
            app.BtnRestoreGraphRel.Enable = 'on';
            app.BtnOpenGraphAbs.Enable = 'on';
            app.BtnRestoreGraphAbs.Enable = 'on';
            app.BtnUpdateGraphAbs.Enable = 'on';
        end

        % Button pushed function: SettingsBtn
        function EditROIPressed(app, event)
            app.EditROIApp = EditROIApp(app);
          %  app, app.RoiController.ROICollections{app.ROIRowIndex}.Name, app.RoiController.ROICollections{app.ROIRowIndex}.Name)
        end

        % Button pushed function: DeleteROICollection
        function DeleteROICollectionPressed2(app, event)
            % Check if a row in the table has been selected before executing the deletion
            if (isempty(app.ROIRowIndex) ~= 1)
                
                % Display a warning
                deleteROIColCfm = uiconfirm(app.BOLDAnalysisToolUIFigure,'This will delete the ROI from every image it is drawn on. Proceed?',...
                    'Confirm delete', 'Icon', 'Warning');
                
                    % Delete if the user selected OK in the warning message
                if (strcmp('OK', deleteROIColCfm) == 1)
                    app.RoiController.DeleteROICollection(app.ROIRowIndex, app.FrameField.Value, app.SliceField.Value);
                    % Call UITable data again to update the table after deletion
                    [cola, colb] = app.RoiController.getROITable;
                    app.UITable.Data = [cola,colb];
                    
                    % When you delecte a collection, the chosen ROWIndex might no longer be correct. Therefore, all buttons with functionality belonging 
                    % to a specific ROI are disabled until another ROW in the ROI table is chosen
                    app.BtnContinueROI.Enable = 'off';
                    app.DeleteROICollection.Enable = 'off';
                    app.SettingsBtn.Enable = 'off';
                    app.BtnDeleteROI.Enable = 'off';
                    app.Button.Enable = 'off';
                    app.BtnDeleteProceedingROIs.Enable = 'off';
                    app.BtnDrawROIThrough.Enable = 'off';
                    
                    % When you delete a collection the BOLD analysis plot is updated
                    app.BoldAnalyzer.DeleteROICollection(app.ROIRowIndex);
                    app.plotAbsAndRel;
                    
                    app.ROIRowIndex = [];
                    
                    app.AveragepixelvalueEditField.Value = 0;
                    app.StandarddeviationEditField.Value = 0;
                    
                end

            end
        end

        % Button pushed function: BtnContinueROI
        function BtnContinueROIPressed(app, event)
            ROI = drawpolygon(app.ax,'Color',app.RoiController.ROICollections{app.ROIRowIndex}.Color,'LineWidth',1);
            app.RoiController.AddROI(app.ROIRowIndex,app.FrameField.Value,app.SliceField.Value,ROI);  
            if(app.RoiController.ROICollections{app.ROIRowIndex}.AnalysisStatus ==1)
                app.getAbsValues;
                app.plotAbsAndRel;
            end
            app.updateAvgAndStdFields;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.ROIRowIndex = event.Indices(1);
            app.DeleteROICollection.Enable = 'on';
            app.SettingsBtn.Enable = 'on';
            app.BtnDeleteROI.Enable = 'on';
            app.BtnDeleteProceedingROIs.Enable = 'on';
            app.Button.Enable = 'on';
            app.BtnDrawROIThrough.Enable = 'on';
            
            if(app.RoiController.ROICollections{app.ROIRowIndex}.AnalysisStatus == 1)
                app.BtnContinueROI.Enable = 'on';
            else
                app.BtnContinueROI.Enable = 'off';
            end
            app.updateAvgAndStdFields;
        end

        % Button pushed function: BtnDeleteROI
        function BtnDeleteROIButtonPushed(app, event)
            % Check if a row in the table has been selected before executing the deletion
            if (isempty(app.ROIRowIndex) ~= 1)
                
                % Display a warning
                deleteROICfm = uiconfirm(app.BOLDAnalysisToolUIFigure,'This will delete every ROI for the selected collection drawn on this picture only. Proceed?',...
                    'Confirm delete', 'Icon', 'Warning');
                
                    % Delete if the user selected OK in the warning message
                if (strcmp('OK', deleteROICfm) == 1)
                    app.RoiController.DeleteROI(app.ROIRowIndex, app.FrameField.Value, app.SliceField.Value)
                    app.getAbsValues;
                    app.plotAbsAndRel;
                end
            end
        end

        % Button pushed function: Button
        function ATBtnPressed2(app, event)
             d = uiprogressdlg(app.BOLDAnalysisToolUIFigure, 'Title', 'Performing autotracking. Hang on...', 'Indeterminate','on');
             app.saveROIPositions(app.SliceField.Value, app.FrameField.Value);
             app.RoiController.StartAutotracking(app.dicomDisplay, app.SliceField.Value, app.FrameField.Value, max(app.FrameSlider.Limits), app.ROIRowIndex);
             app.BoldAnalyzer.UpdateAnalysis(app.dicomDisplay, app.RoiController); 
             app.plotAbsAndRel; 
             close(d);
        end

        % Cell edit callback: UITable
        function EditVisibility(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if(newData==0)
                app.BtnContinueROI.Enable = 'off';
                app.Button.Enable = 'off';
                app.BtnDeleteROI.Enable = 'off';
                app.BtnDeleteProceedingROIs.Enable = 'off';
                app.BtnDrawROIThrough.Enable = 'off';
                app.saveROIPositions(app.SliceField.Value,app.FrameField.Value);
            else
                app.BtnContinueROI.Enable = 'on';
                app.Button.Enable = 'on';
                app.BtnDeleteROI.Enable = 'on';
                app.BtnDeleteProceedingROIs.Enable = 'on';
                app.BtnDrawROIThrough.Enable = 'on';
                app.BaselineEditField.Enable = 'on';
                app.BaselineEditFieldLabel.Enable = 'on';
                app.BtnOpenGraphRel.Enable = 'on';
                app.BtnUpdateGraphRel.Enable = 'on';
                app.BtnRestoreGraphRel.Enable = 'on';
                app.BtnOpenGraphAbs.Enable = 'on';
                app.BtnRestoreGraphAbs.Enable = 'on';
                app.BtnUpdateGraphAbs.Enable = 'on';
            end
            app.RoiController.setAnalysisStatus(indices(1),newData);
            app.plotAbsAndRel;
            displayROIs(app,app.SliceField.Value,app.FrameField.Value);
        end

        % Button pushed function: UpdateROIInfo
        function UpdateROIInfoPressed(app, event)
            app.saveROIPositions(app.SliceField.Value, app.FrameField.Value);
            app.updateAvgAndStdFields;
        end

        % Button pushed function: BtnOpenGraphAbs
        function BtnOpenGraphAbsPressed(app, event)
            AbsValues = app.BoldAnalyzer.absValues;
            if(~isempty(AbsValues))
                % cla cleares uiaxes: https://se.mathworks.com/help/matlab/ref/cla.html
                a = figure('Name', 'Visualization of absolute BOLD values', 'NumberTitle', 'off');
                % Hold on to UI axes makes it possible to plot multiple plots in the same axes: https://se.mathworks.com/help/matlab/creating_guis/graphics-support-in-app-designer.html
                hold on;
                legendCounter = 1;
                for i=1: length(AbsValues)
                    if(~isempty(AbsValues{i}))
                        plot(AbsValues{i}.XValues, AbsValues{i}.CollectionValues,'Color',app.RoiController.ROICollections{i}.Color);
                        legends{legendCounter} = app.RoiController.ROICollections{i}.Name;
                        legendCounter = legendCounter+1;
                        xlabel('Frames')
                        ylabel('BOLD values');
                    end
                end
                %Legend is added to the UIAxes: https://se.mathworks.com/help/matlab/ref/legend.html
                legend(legends);
                hold off;
            end
        end

        % Callback function: BOLDAnalysisToolUIFigure, ExitMenu
        function closeFcn(app, event)
            % Display a warning
                deleteROICfm = uiconfirm(app.BOLDAnalysisToolUIFigure,'Are you sure you want to exit the application? Any unsaved work will be lost.',...
                    'Confirm exit', 'Icon', 'Warning');
                
                    % Exit if the user selected OK in the warning message
                if (strcmp('OK', deleteROICfm) == 1)
                    delete(app)
                end
        end

        % Menu selected function: SaveMenu
        function SavePressed(app, event)
            [file, path] = uiputfile('*.mat');
            
            if(file~=0)
                d = uiprogressdlg(app.BOLDAnalysisToolUIFigure, 'Title', 'Saving data. Please wait...', 'Indeterminate', 'on');
                app.BOLDAnalysisToolUIFigure.Visible = 'off';
                app.BOLDAnalysisToolUIFigure.Visible = 'on';
                filename = fullfile(path,file);
                app.fileHandler.MATSave(app.dicomDisplay,app.RoiController,filename);
                close(d);
            end
        end

        % Menu selected function: ExporttoExcelMenu
        function ExportToExcelPressed(app, event)
            [file,path] = uiputfile('*.xlsx');
            
            if(file~=0)
                d = uiprogressdlg(app.BOLDAnalysisToolUIFigure, 'Title', 'Exporting to Excel. Please wait...', 'Indeterminate', 'on');
                app.BOLDAnalysisToolUIFigure.Visible = 'off';
                app.BOLDAnalysisToolUIFigure.Visible = 'on';
                filename = fullfile(path,file);
                % Creating struct with column names:
                for i=1:length(app.RoiController.ROICollections)
                  if (app.RoiController.ROICollections{i}.AnalysisStatus == 1)
                       columnNames{i} = app.RoiController.ROICollections{i}.Name;
                  end
                end
            
                % Creating struct with BOLD values:
                absValues = app.BoldAnalyzer.absValues;
                for i = 1:length(absValues)
                    relValues{i} = app.BoldAnalyzer.GetRelativeValues(absValues{i}.CollectionValues, app.BaselineEditField.Value);
                end
                
                totalFrames = max(app.FrameSlider.Limits);
                
                % Calling the save function with the created variables as parameters:
                app.fileHandler.XLSXSave(columnNames, absValues, relValues, totalFrames, filename); 
                
                close(d);
            end
        end

        % Button pushed function: BtnRestoreGraphAbs
        function RestoreGraphAbsPressed(app, event)
            ymax = 0;
            ymin = 65536;
            xmin = 5000;
            xmax = 0;
            for i=1: length(app.BoldAnalyzer.absValues)
                if(app.RoiController.ROICollections{i}.AnalysisStatus ==1)
                    
                    %Determining the x values
                    if(min(app.BoldAnalyzer.absValues{i}.CollectionValues)<ymin)
                        ymin = min(app.BoldAnalyzer.absValues{i}.CollectionValues);
                    end
                    if(max(app.BoldAnalyzer.absValues{i}.CollectionValues)>ymax)
                        ymax = max(app.BoldAnalyzer.absValues{i}.CollectionValues);
                    end
                    
                    % Determining the x values
                    if(max(app.BoldAnalyzer.absValues{i}.XValues)>xmax)
                        xmax = max(app.BoldAnalyzer.absValues{i}.XValues);
                    end
                    if(min(app.BoldAnalyzer.absValues{i}.XValues)<xmin)
                        xmin = min(app.BoldAnalyzer.absValues{i}.XValues);
                    end
                end
            end

            app.UIAxes2_2.XLim = [xmin, xmax];
            app.UIAxes2_2.YLim = [ymin, ymax];
        end

        % Menu selected function: OpenexistingMenu
        function OpenExistingPress(app, event)
            [file,path] = uigetfile('*.mat');
            
            if(file~=0)
                app.BOLDAnalysisToolUIFigure.Visible = 'off';
                app.BOLDAnalysisToolUIFigure.Visible = 'on';
                d = uiprogressdlg(app.BOLDAnalysisToolUIFigure, 'Title', 'Loading your data. Please wait...', 'Indeterminate','on', 'Message','Reading file');
                filename = fullfile(path,file);
                [app.dicomDisplay, app.RoiController, app.dicomFile, app.numberOfFrames, app.numberOfSlices] = app.fileHandler.MATRead(filename);
                
                d.Message = 'Setting up user interface';
                app.FrameSlider.Limits = [1 app.numberOfFrames];
                app.SliceSlider.Limits = [1 app.numberOfSlices];
                imshow(app.dicomFile.pixelData,'parent',app.ax);
                app.modifiedDicomGamma = [];
                app.modifiedDicomHist = [];
                app.MetaTable.Data = app.dicomFile.dicomInfo;   
                app.xaxeslims = app.ax.XLim;
                app.yaxeslims = app.ax.YLim;
                app.MetaBtn.Enable = 'on';
                app.SliceField.Value = 1;
                app.FrameField.Value = 1;
                app.SliceSlider.Value = 1;
                app.FrameSlider.Value = 1;
                app.WelcomeLabel.Visible = 'off';
                app.WelcomeLabel2.Visible = 'off';
                app.WelcomeLabel3.Visible = 'off';
                app.MetaBtn.Enable = 'on';
                app.UpdateROIInfo.Enable = 'on';
                app.BtnHistEq.Enable = 'on';
                app.BtnGammaCorShow.Enable = 'on';
                app.BtnZoomOut.Enable = 'on';
                app.FrameSlider.Enable = 'on';
                app.FrameSliderLabel.Enable = 'on';
                app.SliceSlider.Enable = 'on';
                app.SliceSliderLabel.Enable = 'on'; 
                app.FrameField.Enable = 'on';
                app.SliceField.Enable = 'on';
                app.SaveMenu.Enable = 'on';
                app.NewcollectionMenu.Enable = 'on';
                app.ExporttoExcelMenu.Enable = 'on';

                app.AveragepixelvalueEditField.Enable = 'on';
                app.AveragepixelvalueLabel.Enable = 'on';
                app.StandarddeviationEditField.Enable = 'on';
                app.StandarddeviationLabel.Enable = 'on';
                

                % If a collection exists. Give the user access to ROI tools.
                for i = 1:length(app.RoiController.ROICollections)
                    app.Button.Enable = 'on';
                    app.BtnDeleteROI.Enable = 'on';
                    app.BtnDeleteProceedingROIs.Enable = 'on';
                    app.BtnContinueROI.Enable = 'on';
                    app.BtnDrawROIThrough.Enable = 'on';
                    
                    % If a collection is visible. Also give the user access to graph tools.
                    if(app.RoiController.ROICollections{i}.AnalysisStatus == 1)
                        app.BaselineEditField.Enable = 'on';
                        app.BaselineEditFieldLabel.Enable = 'on';
                        app.BtnOpenGraphRel.Enable = 'on';
                        app.BtnUpdateGraphRel.Enable = 'on';
                        app.BtnRestoreGraphRel.Enable = 'on';
                        app.BtnOpenGraphAbs.Enable = 'on';
                        app.BtnRestoreGraphAbs.Enable = 'on';
                        app.BtnUpdateGraphAbs.Enable = 'on';
                        break
                    end
                end


                d.Message = 'Setting up analysis results';
                app.displayROIs(1,1);
                [cola, colb] = app.RoiController.getROITable;
                app.UITable.Data = [cola,colb];
                app.BoldAnalyzer.UpdateAnalysis(app.dicomDisplay, app.RoiController);
                app.plotAbsAndRel;
                app.updateAvgAndStdFields;
                
                close(d);
            end
        end

        % Value changed function: BaselineEditField
        function BaselineChanged(app, event)
            value = app.BaselineEditField.Value;
            if(~isempty(value))
               app.plotAbsAndRel;
            end
        end

        % Button pushed function: BtnOpenGraphRel
        function BtnOpenGraphRelPressed(app, event)
            absValues = app.BoldAnalyzer.absValues;
            baselineValue = app.BaselineEditField.Value;
            for i = 1:length(absValues)
                relValues{i} = app.BoldAnalyzer.GetRelativeValues(absValues{i}.CollectionValues, baselineValue);
            end
            
            if(~isempty(relValues))
                % cla cleares uiaxes: https://se.mathworks.com/help/matlab/ref/cla.html
                a = figure('Name', 'Visualization of relative BOLD values', 'NumberTitle', 'off');
                % Hold on to UI axes makes it possible to plot multiple plots in the same axes: https://se.mathworks.com/help/matlab/creating_guis/graphics-support-in-app-designer.html
                hold on;
                legendCounter = 1;
                for i=1: length(relValues)
                    if(~isempty(relValues{i}))
                        plot(absValues{i}.XValues, relValues{i},'Color',app.RoiController.ROICollections{i}.Color);
                        legends{legendCounter} = app.RoiController.ROICollections{i}.Name;
                        legendCounter = legendCounter+1;
                        xlabel('Frames')
                        ylabel('BOLD values');
                    end
                end
                %Legend is added to the UIAxes: https://se.mathworks.com/help/matlab/ref/legend.html
                legend(legends);
                hold off;
            end
        end

        % Menu selected function: NewMenu_2
        function OpenNewWindow(app, event)
            msg = 'Do you want to close this window before opening a new one? Warning: Remember to save your work if you choose to close it.';
            title = 'Select an option';
            openNewCfm = uiconfirm(app.BOLDAnalysisToolUIFigure,msg,title,...
           'Options',{'Keep both','Close this','Cancel'},...
           'DefaultOption',1,'CancelOption',3);
            
            if (strcmp('Keep both', openNewCfm) == 1)
                MainWindow;
            end
            
            if (strcmp('Close this', openNewCfm) == 1)
                MainWindow;
                delete(app);
            end
        end

        % Button pushed function: BtnRestoreGraphRel
        function RestoreGraphRelPressed(app, event)
            ymax = 0;
            ymin = 65536;
            xmin = 5000;
            xmax = 0;
            baselineValue = app.BaselineEditField.Value;
            for i=1: length(app.BoldAnalyzer.absValues)
                if(app.RoiController.ROICollections{i}.AnalysisStatus ==1)
                    
                    % Determining the y values
                    if(min(app.BoldAnalyzer.GetRelativeValues(app.BoldAnalyzer.absValues{i}.CollectionValues, baselineValue))<ymin)
                        ymin = min(app.BoldAnalyzer.GetRelativeValues(app.BoldAnalyzer.absValues{i}.CollectionValues, baselineValue));
                    end
                    if(max(app.BoldAnalyzer.GetRelativeValues(app.BoldAnalyzer.absValues{i}.CollectionValues, baselineValue))>ymax)
                        ymax = max(app.BoldAnalyzer.GetRelativeValues(app.BoldAnalyzer.absValues{i}.CollectionValues, baselineValue));
                    end
                    
                    % Determining the x values
                    if(max(app.BoldAnalyzer.absValues{i}.XValues)>xmax)
                        xmax = max(app.BoldAnalyzer.absValues{i}.XValues);
                    end
                    if(min(app.BoldAnalyzer.absValues{i}.XValues)<xmin)
                        xmin = min(app.BoldAnalyzer.absValues{i}.XValues);
                    end
                end
            end
                        
            app.UIAxes2.XLim = [xmin, xmax];
            app.UIAxes2.YLim = [ymin, ymax];
            app.UIAxes2.YTickLabelMode = 'auto';
        end

        % Button pushed function: BtnUpdateGraphAbs
        function BtnUpdateGraphAbsPressed(app, event)
            d = uiprogressdlg(app.BOLDAnalysisToolUIFigure, 'Title', 'Updating analysis results. Hang on...', 'Indeterminate','on');
            app.saveROIPositions(app.SliceField.Value, app.FrameField.Value);
            app.plotAbsAndRel;
            close(d);
        end

        % Button pushed function: BtnUpdateGraphRel
        function BtnUpdateGraphRelPressed(app, event)
            d = uiprogressdlg(app.BOLDAnalysisToolUIFigure, 'Title', 'Updating analysis results. Hang on...', 'Indeterminate','on');
            app.saveROIPositions(app.SliceField.Value, app.FrameField.Value);
            app.plotAbsAndRel;
            close(d);
        end

        % Menu selected function: CreditsMenu
        function CreditsPressed2(app, event)
            message = sprintf('This software was developed as part of a bachelor project at Aarhus School of Engineering, Aarhus University, December 2018 \n \n By: \n Christian Nelby \n Mads Nielsen \n Oliver Ahrenfeldt \n \n Special thanks to Thomas Nielsen, Aarhus University (counselor), Anne Sørensen, Aalborg University Hospital (external guidance) and David Alberg Peters, Aarhus University Hospital (external guidance)');
            uialert(app.BOLDAnalysisToolUIFigure,message,'Credits and acknowledgements', 'Icon', 'info');
        end

        % Button pushed function: BtnDeleteProceedingROIs
        function DeleteProceedingROI(app, event)
            % Asks for user confirm
            msg = 'This will delete all ROIs on this and all proceeding frames in this slice for the selected collection. Proceed?';
            title = 'Confirm delete';
            openNewCfm = uiconfirm(app.BOLDAnalysisToolUIFigure,msg,title, 'Icon', 'warning');
            
            if (strcmp('OK', openNewCfm) == 1)
                d = uiprogressdlg(app.BOLDAnalysisToolUIFigure, 'Title', 'Deleting ROIs. Hang on...', 'Indeterminate','on');
                % Deletes all proceeding ROIs
                for i= app.FrameField.Value: max(app.FrameSlider.Limits)
                    app.RoiController.DeleteROI(app.ROIRowIndex, i, app.SliceField.Value);
                    %Tries to find a x value corresponding to the current frame in the loop. If a XValues is found, 
                    %the XValue and the BOLD value is deleted
                    idx = find(app.BoldAnalyzer.absValues{app.ROIRowIndex}.XValues == i);
                    if(~isempty(idx))
                        app.BoldAnalyzer.absValues{app.ROIRowIndex}.XValues(idx) = [];
                        app.BoldAnalyzer.absValues{app.ROIRowIndex}.CollectionValues(idx) = [];
                    end
                end
                % The plot and the textfields with the average value and the standard deviation for the current frame are deleted.
                d.Message = 'Updating analysis results';
                app.BoldAnalyzer.UpdateAnalysis(app.dicomDisplay, app.RoiController);
                app.plotAbsAndRel;
                app.updateAvgAndStdFields;     
                close(d);
            end
        end

        % Button pushed function: BtnDrawROIThrough
        function BtnDrawROIThroughPressed(app, event)
             d = uiprogressdlg(app.BOLDAnalysisToolUIFigure, 'Title', 'Copying ROI. Hang on...', 'Indeterminate','on');
             app.saveROIPositions(app.SliceField.Value, app.FrameField.Value);
             app.RoiController.DrawROIsThrough(app.SliceField.Value, app.FrameField.Value, max(app.FrameSlider.Limits), app.ROIRowIndex);
             d.Message = 'Updating analysis results';
             app.BoldAnalyzer.UpdateAnalysis(app.dicomDisplay, app.RoiController); 
             app.plotAbsAndRel;
             close(d);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BOLDAnalysisToolUIFigure = uifigure;
            app.BOLDAnalysisToolUIFigure.Name = 'BOLD Analysis Tool';
            app.BOLDAnalysisToolUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.BOLDAnalysisToolUIFigure.KeyPressFcn = createCallbackFcn(app, @BOLDAnalysisToolUIFigureKeyPress, true);
            app.FileMenu = uimenu(app.BOLDAnalysisToolUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu_2 = uimenu(app.FileMenu);
            app.NewMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OpenNewWindow, true);
            app.NewMenu_2.Tooltip = {'Open a new project.'};
            app.NewMenu_2.Accelerator = 'N';
            app.NewMenu_2.Text = 'New';
            app.OpennewMenu = uimenu(app.FileMenu);
            app.OpennewMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenDialogPress2, true);
            app.OpennewMenu.Tooltip = {'Open a new BOLD dataset.'};
            app.OpennewMenu.Accelerator = 'o';
            app.OpennewMenu.Text = 'Open new';
            app.OpenexistingMenu = uimenu(app.FileMenu);
            app.OpenexistingMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenExistingPress, true);
            app.OpenexistingMenu.Tooltip = {'Open a previously saved .Mat file.'};
            app.OpenexistingMenu.Accelerator = 'e';
            app.OpenexistingMenu.Text = 'Open existing';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SavePressed, true);
            app.SaveMenu.Tooltip = {'Save your work in a .Mat file.'};
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Separator = 'on';
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.ExporttoExcelMenu = uimenu(app.FileMenu);
            app.ExporttoExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportToExcelPressed, true);
            app.ExporttoExcelMenu.Tooltip = {'Export the BOLD values as an .xlsx file. This will only export the currently visible signals.'};
            app.ExporttoExcelMenu.Enable = 'off';
            app.ExporttoExcelMenu.Text = 'Export to Excel';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @closeFcn, true);
            app.ExitMenu.Tooltip = {'Exit application.'};
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.ROIMenu = uimenu(app.BOLDAnalysisToolUIFigure);
            app.ROIMenu.Text = 'ROI';
            app.NewcollectionMenu = uimenu(app.ROIMenu);
            app.NewcollectionMenu.MenuSelectedFcn = createCallbackFcn(app, @NewROIPressed2, true);
            app.NewcollectionMenu.Tooltip = {'Start drawing a new ROI collection.'};
            app.NewcollectionMenu.Enable = 'off';
            app.NewcollectionMenu.Accelerator = 'r';
            app.NewcollectionMenu.Text = 'New collection';
            app.AboutMenu = uimenu(app.BOLDAnalysisToolUIFigure);
            app.AboutMenu.Text = 'About';
            app.CreditsMenu = uimenu(app.AboutMenu);
            app.CreditsMenu.MenuSelectedFcn = createCallbackFcn(app, @CreditsPressed2, true);
            app.CreditsMenu.Text = 'Credits';
            app.UITable = uitable(app.BOLDAnalysisToolUIFigure);
            app.UITable.ColumnName = {'Name'; 'Visible'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @EditVisibility, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Tooltip = {'ROI collections'};
            app.MetadataPanel = uipanel(app.BOLDAnalysisToolUIFigure);
            app.MetadataPanel.Title = 'Metadata';
            app.MetaTable = uitable(app.MetadataPanel);
            app.MetaTable.ColumnName = {'Header'; 'Data'};
            app.MetaTable.RowName = {};
            app.MetaBtn = uibutton(app.MetadataPanel, 'push');
            app.MetaBtn.ButtonPushedFcn = createCallbackFcn(app, @MetaBtnPushed, true);
            app.MetaBtn.Icon = 'icon_info.png';
            app.MetaBtn.Enable = 'off';
            app.MetaBtn.Tooltip = {'Open all metadata related to the current image in a new window'};
            app.MetaBtn.Text = '';
            app.ROIinformationPanel = uipanel(app.BOLDAnalysisToolUIFigure);
            app.ROIinformationPanel.Tooltip = {'Displays information related to the selected ROI collection on the current image'};
            app.ROIinformationPanel.Title = 'ROI information';
            app.AveragepixelvalueLabel = uilabel(app.ROIinformationPanel);
            app.AveragepixelvalueLabel.Enable = 'off';
            app.AveragepixelvalueLabel.Text = 'Average pixel value';
            app.AveragepixelvalueEditField = uieditfield(app.ROIinformationPanel, 'numeric');
            app.AveragepixelvalueEditField.Enable = 'off';
            app.StandarddeviationLabel = uilabel(app.ROIinformationPanel);
            app.StandarddeviationLabel.Enable = 'off';
            app.StandarddeviationLabel.Text = 'Standard deviation';
            app.StandarddeviationEditField = uieditfield(app.ROIinformationPanel, 'numeric');
            app.StandarddeviationEditField.Enable = 'off';
            app.TabGroup = uitabgroup(app.BOLDAnalysisToolUIFigure);
            app.RelativeBOLDTab = uitab(app.TabGroup);
            app.RelativeBOLDTab.Title = 'Relative BOLD';
            app.UIAxes2 = uiaxes(app.RelativeBOLDTab);
            title(app.UIAxes2, 'BOLD Signal')
            xlabel(app.UIAxes2, 'Frame')
            ylabel(app.UIAxes2, 'BOLD Value')
            app.BaselineEditFieldLabel = uilabel(app.RelativeBOLDTab);
            app.BaselineEditFieldLabel.Enable = 'off';
            app.BaselineEditFieldLabel.Text = 'Baseline';
            app.BaselineEditField = uieditfield(app.RelativeBOLDTab, 'numeric');
            app.BaselineEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineChanged, true);
            app.BaselineEditField.Enable = 'off';
            app.BaselineEditField.Tooltip = {'Adjust the baseline for the calculation of relative BOLD values'};
            app.BaselineEditField.Value = 1;
            app.BtnOpenGraphRel = uibutton(app.RelativeBOLDTab, 'push');
            app.BtnOpenGraphRel.ButtonPushedFcn = createCallbackFcn(app, @BtnOpenGraphRelPressed, true);
            app.BtnOpenGraphRel.Icon = 'GoTo Icon.png';
            app.BtnOpenGraphRel.Enable = 'off';
            app.BtnOpenGraphRel.Tooltip = {'Open the current graph in a new window with additional features'};
            app.BtnOpenGraphRel.Text = '';
            app.BtnRestoreGraphRel = uibutton(app.RelativeBOLDTab, 'push');
            app.BtnRestoreGraphRel.ButtonPushedFcn = createCallbackFcn(app, @RestoreGraphRelPressed, true);
            app.BtnRestoreGraphRel.Icon = 'ScaleAxesIcon3.png';
            app.BtnRestoreGraphRel.Enable = 'off';
            app.BtnRestoreGraphRel.Tooltip = {'Restore view'};
            app.BtnRestoreGraphRel.Text = '';
            app.BtnUpdateGraphRel = uibutton(app.RelativeBOLDTab, 'push');
            app.BtnUpdateGraphRel.ButtonPushedFcn = createCallbackFcn(app, @BtnUpdateGraphRelPressed, true);
            app.BtnUpdateGraphRel.Icon = 'Update Icon 2.png';
            app.BtnUpdateGraphRel.Enable = 'off';
            app.BtnUpdateGraphRel.Tooltip = {'Refresh plot. Use this whenever a ROI has been moved.'};
            app.BtnUpdateGraphRel.Text = '';
            app.AbsoluteBOLDTab = uitab(app.TabGroup);
            app.AbsoluteBOLDTab.Title = 'Absolute BOLD';
            app.UIAxes2_2 = uiaxes(app.AbsoluteBOLDTab);
            title(app.UIAxes2_2, 'BOLD Signal')
            xlabel(app.UIAxes2_2, 'Frame')
            ylabel(app.UIAxes2_2, 'BOLD Value')
            app.BtnOpenGraphAbs = uibutton(app.AbsoluteBOLDTab, 'push');
            app.BtnOpenGraphAbs.ButtonPushedFcn = createCallbackFcn(app, @BtnOpenGraphAbsPressed, true);
            app.BtnOpenGraphAbs.Icon = 'GoTo Icon.png';
            app.BtnOpenGraphAbs.Enable = 'off';
            app.BtnOpenGraphAbs.Tooltip = {'Open the current graph in a new window with additional features'};
            app.BtnOpenGraphAbs.Text = '';
            app.BtnRestoreGraphAbs = uibutton(app.AbsoluteBOLDTab, 'push');
            app.BtnRestoreGraphAbs.ButtonPushedFcn = createCallbackFcn(app, @RestoreGraphAbsPressed, true);
            app.BtnRestoreGraphAbs.Icon = 'ScaleAxesIcon3.png';
            app.BtnRestoreGraphAbs.Enable = 'off';
            app.BtnRestoreGraphAbs.Tooltip = {'Restore view'};
            app.BtnRestoreGraphAbs.Text = '';
            app.BtnUpdateGraphAbs = uibutton(app.AbsoluteBOLDTab, 'push');
            app.BtnUpdateGraphAbs.ButtonPushedFcn = createCallbackFcn(app, @BtnUpdateGraphAbsPressed, true);
            app.BtnUpdateGraphAbs.Icon = 'Update Icon 2.png';
            app.BtnUpdateGraphAbs.Enable = 'off';
            app.BtnUpdateGraphAbs.Tooltip = {'Refresh plot. Use this whenever a ROI has been moved.'};
            app.BtnUpdateGraphAbs.Text = '';
            app.DeleteROICollection = uibutton(app.BOLDAnalysisToolUIFigure, 'push');
            app.DeleteROICollection.ButtonPushedFcn = createCallbackFcn(app, @DeleteROICollectionPressed2, true);
            app.DeleteROICollection.Icon = 'ClearAll_24.png';
            app.DeleteROICollection.Enable = 'off';
            app.DeleteROICollection.Tooltip = {'Delete the selected ROI collection'};
            app.DeleteROICollection.Text = '';
            app.SettingsBtn = uibutton(app.BOLDAnalysisToolUIFigure, 'push');
            app.SettingsBtn.ButtonPushedFcn = createCallbackFcn(app, @EditROIPressed, true);
            app.SettingsBtn.Icon = 'settings_grey_192x192.png';
            app.SettingsBtn.Enable = 'off';
            app.SettingsBtn.Tooltip = {'Open ROI collection settings menu'};
            app.SettingsBtn.Text = '';
            app.GammacorrectionPanel = uipanel(app.BOLDAnalysisToolUIFigure);
            app.GammacorrectionPanel.Title = 'Gamma correction';
            app.GammacorrectionPanel.Visible = 'off';
            app.CoefficientSliderLabel = uilabel(app.GammacorrectionPanel);
            app.CoefficientSliderLabel.Enable = 'off';
            app.CoefficientSliderLabel.Text = 'Coefficient:';
            app.CoefficientSlider = uislider(app.GammacorrectionPanel);
            app.CoefficientSlider.Limits = [0 5];
            app.CoefficientSlider.ValueChangedFcn = createCallbackFcn(app, @gammaValueChanged, true);
            app.CoefficientSlider.Enable = 'off';
            app.CoefficientSlider.Value = 1;
            app.Label = uilabel(app.GammacorrectionPanel);
            app.Label.Text = '';
            app.Switch = uiswitch(app.GammacorrectionPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @gammaSwitchPressed, true);
            app.CoefficientField = uieditfield(app.GammacorrectionPanel, 'numeric');
            app.CoefficientField.ValueChangedFcn = createCallbackFcn(app, @CoefficientFieldChanged, true);
            app.CoefficientField.Enable = 'off';
            app.CoefficientField.Value = 1;
            app.SliceField = uieditfield(app.BOLDAnalysisToolUIFigure, 'numeric');
            app.SliceField.ValueChangedFcn = createCallbackFcn(app, @SliceFieldValueChanged, true);
            app.SliceField.Enable = 'off';
            app.SliceField.Tooltip = {'Current slice number'};
            app.SliceField.Value = 1;
            app.SliceSliderLabel = uilabel(app.BOLDAnalysisToolUIFigure);
            app.SliceSliderLabel.Enable = 'off';
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.BOLDAnalysisToolUIFigure);
            app.SliceSlider.Limits = [1 100];
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.Enable = 'off';
            app.SliceSlider.Value = 1;
            app.FrameField = uieditfield(app.BOLDAnalysisToolUIFigure, 'numeric');
            app.FrameField.ValueChangedFcn = createCallbackFcn(app, @FrameFieldValueChanged, true);
            app.FrameField.Enable = 'off';
            app.FrameField.Tooltip = {'Current frame number'};
            app.FrameField.Value = 1;
            app.FrameSliderLabel = uilabel(app.BOLDAnalysisToolUIFigure);
            app.FrameSliderLabel.Enable = 'off';
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.BOLDAnalysisToolUIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Enable = 'off';
            app.FrameSlider.Value = 1;
            app.UpdateROIInfo = uibutton(app.BOLDAnalysisToolUIFigure, 'push');
            app.UpdateROIInfo.ButtonPushedFcn = createCallbackFcn(app, @UpdateROIInfoPressed, true);
            app.UpdateROIInfo.Icon = 'Update Icon 2.png';
            app.UpdateROIInfo.Enable = 'off';
            app.UpdateROIInfo.Tooltip = {'Refresh information. Use this whenever a ROI has been moved.'};
            app.UpdateROIInfo.Text = '';
            app.WelcomeLabel = uilabel(app.BOLDAnalysisToolUIFigure);
            app.WelcomeLabel.Text = 'Welcome';
            app.WelcomeLabel2 = uilabel(app.BOLDAnalysisToolUIFigure);
            app.WelcomeLabel2.Text = 'Please open a BOLD MRI scan to perform an analysis';
            app.WelcomeLabel3 = uilabel(app.BOLDAnalysisToolUIFigure);
            app.WelcomeLabel3.Text = 'Tip: In the upper left corner under "File" choose "Open new" or "Open existing".';
            app.ToolsPanel = uipanel(app.BOLDAnalysisToolUIFigure);
            app.ToolsPanel.Title = 'Tools';
            app.BtnZoomOut = uibutton(app.ToolsPanel, 'push');
            app.BtnZoomOut.ButtonPushedFcn = createCallbackFcn(app, @BtnZoomOutButtonPushed, true);
            app.BtnZoomOut.Icon = 'ScaleAxesIcon3.png';
            app.BtnZoomOut.Enable = 'off';
            app.BtnZoomOut.Tooltip = {'Restore view'};
            app.BtnZoomOut.Text = '';
            app.BtnContinueROI = uibutton(app.ToolsPanel, 'push');
            app.BtnContinueROI.ButtonPushedFcn = createCallbackFcn(app, @BtnContinueROIPressed, true);
            app.BtnContinueROI.Icon = 'Polygon_24px.png';
            app.BtnContinueROI.Enable = 'off';
            app.BtnContinueROI.Tooltip = {'Add a new ROI to selected collection. The collection must be visible.'};
            app.BtnContinueROI.Text = '';
            app.BtnGammaCorShow = uibutton(app.ToolsPanel, 'state');
            app.BtnGammaCorShow.ValueChangedFcn = createCallbackFcn(app, @BtnGammaCorShowButtonPushed, true);
            app.BtnGammaCorShow.Enable = 'off';
            app.BtnGammaCorShow.Tooltip = {'Gamma correction'};
            app.BtnGammaCorShow.Icon = 'Gamma.png';
            app.BtnGammaCorShow.Text = '';
            app.BtnHistEq = uibutton(app.ToolsPanel, 'state');
            app.BtnHistEq.ValueChangedFcn = createCallbackFcn(app, @HistBtnPressed, true);
            app.BtnHistEq.Enable = 'off';
            app.BtnHistEq.Tooltip = {'Histogram equalization'};
            app.BtnHistEq.Icon = 'Histogram.png';
            app.BtnHistEq.Text = '';
            app.BtnDeleteROI = uibutton(app.ToolsPanel, 'push');
            app.BtnDeleteROI.ButtonPushedFcn = createCallbackFcn(app, @BtnDeleteROIButtonPushed, true);
            app.BtnDeleteROI.Icon = 'DeleteROI.png';
            app.BtnDeleteROI.Enable = 'off';
            app.BtnDeleteROI.Tooltip = {'Delete ROI for the selected collection on this image only'};
            app.BtnDeleteROI.Text = '';
            app.Button = uibutton(app.ToolsPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ATBtnPressed2, true);
            app.Button.Icon = 'targetIcon.png';
            app.Button.Enable = 'off';
            app.Button.Tooltip = {'Autotracking'};
            app.Button.Text = '';
            app.BtnDeleteProceedingROIs = uibutton(app.ToolsPanel, 'push');
            app.BtnDeleteProceedingROIs.ButtonPushedFcn = createCallbackFcn(app, @DeleteProceedingROI, true);
            app.BtnDeleteProceedingROIs.Icon = 'DeleteProceedingROI.png';
            app.BtnDeleteProceedingROIs.Enable = 'off';
            app.BtnDeleteProceedingROIs.Tooltip = {'Delete ROI for the selected collection on this and all proceeding frames on this slice'};
            app.BtnDeleteProceedingROIs.Text = '';
            app.BtnDrawROIThrough = uibutton(app.ToolsPanel, 'push');
            app.BtnDrawROIThrough.ButtonPushedFcn = createCallbackFcn(app, @BtnDrawROIThroughPressed, true);
            app.BtnDrawROIThrough.Icon = 'RoiThroughIcon.png';
            app.BtnDrawROIThrough.Enable = 'off';
            app.BtnDrawROIThrough.Tooltip = {'Copy ROIs for the selected collection and draw it on all proceeding frames on this slice.'};
            app.BtnDrawROIThrough.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MainWindow

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BOLDAnalysisToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BOLDAnalysisToolUIFigure)
        end
    end
end